classdef proyecto_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        MetricasPanel          matlab.ui.container.Panel
        MetricasTextArea       matlab.ui.control.TextArea
        MetricasTextAreaLabel  matlab.ui.control.Label
        ControlesPanel         matlab.ui.container.Panel
        SimularButton          matlab.ui.control.Button
        NEditField             matlab.ui.control.NumericEditField
        NsimbolosLabel         matlab.ui.control.Label
        EbNoEditField          matlab.ui.control.NumericEditField
        EbN0dBLabel            matlab.ui.control.Label
        BetaEditField          matlab.ui.control.NumericEditField
        BetaLabel              matlab.ui.control.Label
        MEditField             matlab.ui.control.NumericEditField
        Label_2                matlab.ui.control.Label
        MLabel                 matlab.ui.control.Label
        TipoDropDown           matlab.ui.control.DropDown
        Label                  matlab.ui.control.Label
        TipoLabel              matlab.ui.control.Label
        UIAxes4                matlab.ui.control.UIAxes
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
%% === 1. Leer parámetros de la GUI ===
            tipo    = app.TipoDropDown.Value;
            M       = round(app.MEditField.Value);
            beta    = app.BetaEditField.Value;
            EbNo_dB = app.EbNoEditField.Value;
            N       = round(app.NEditField.Value);
            
            %% === 2. VALIDACIÓN (RNF1) ===
            if beta < 0 || beta > 1
                uialert(app.UIFigure, ...
                    'Beta (roll-off) debe estar entre 0 y 1.', 'Error');
                return;
            end
            
            if M < 2 || mod(log2(M), 1) ~= 0
                uialert(app.UIFigure, ...
                    'M debe ser una potencia de 2 (2, 4, 8, 16, ...).', 'Error');
                return;
            end
            
            if strcmp(tipo, 'QAM') && mod(log2(M), 2) ~= 0
                uialert(app.UIFigure, ...
                    'Para QAM, M debe ser potencia de 4 (4, 16, 64, 256...).', 'Error');
                return;
            end
            
            if N < 100 || N > 1e6
                uialert(app.UIFigure, ...
                    'N debe estar entre 100 y 1,000,000.', 'Error');
                return;
            end
            
            if EbNo_dB < 0 || EbNo_dB > 40
                uialert(app.UIFigure, ...
                    'Eb/N0 debe estar entre 0 y 40 dB.', 'Error');
                return;
            end
            
            %% === 3. Parámetros fijos ===
            span = 6;
            sps  = 8;
            
            %% === 4. Ejecutar el sistema ===
            % -- Mapeo --
            switch upper(tipo)
                case 'PAM', [simbolos, bits_tx, k] = mapeo_pam(M, N);
                case 'QAM', [simbolos, bits_tx, k] = mapeo_qam(M, N);
                case 'PSK', [simbolos, bits_tx, k] = mapeo_psk(M, N);
            end
            
            % -- Filtro y transmisión --
            h       = filtro_srrc(beta, span, sps);
            retardo = span * sps;
            simb_up = mi_upsample(simbolos, sps);
            sen_tx  = conv(simb_up, h, 'full');
            
            % -- Canal AWGN --
            sen_rx = canal_awgn(sen_tx, EbNo_dB, k);
            
            % -- Filtro Rx y muestreo --
            sen_fil  = conv(sen_rx, h, 'full');
            muestras = sen_fil(retardo+1 : sps : retardo + N*sps);
            
            % -- Decisión --
            C = constelacion(M, tipo);
            [bits_rx, ~] = decisor(muestras, C, k);
            
            % -- BER puntual --
            BER = sum(bits_tx ~= bits_rx) / length(bits_tx);
            
            %% === 5. Gráfica 1: Constelación Rx ===
            cla(app.UIAxes);
            plot(app.UIAxes, real(muestras), imag(muestras), ...
                 'bo', 'MarkerSize', 3);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, real(C), imag(C), ...
                 'rx', 'MarkerSize', 10, 'LineWidth', 2);
            hold(app.UIAxes, 'off');
            grid(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            title(app.UIAxes, sprintf('Constelación Rx %d-%s', M, tipo));
            xlabel(app.UIAxes, 'I');
            ylabel(app.UIAxes, 'Q');
            
            %% === 6. Gráfica 2: Diagrama del ojo ===
            sen_fil_ojo = sen_fil(retardo+1 : end-retardo);
            L = 2*sps;
            num_seg = min(floor((length(sen_fil_ojo) - L)/sps), 400);
            segmentos = zeros(num_seg, L);
            for i = 1:num_seg
                segmentos(i,:) = sen_fil_ojo((i-1)*sps+1 : (i-1)*sps+L).';
            end
            
            cla(app.UIAxes2);
            hold(app.UIAxes2, 'on');
            t_ojo = (0:L-1)/sps;
            for i = 1:num_seg
                plot(app.UIAxes2, t_ojo, real(segmentos(i,:)), ...
                     'Color', [0.6 0.6 0.9]);
            end
            xline(app.UIAxes2, 1, 'r--', 'LineWidth', 1.5);
            hold(app.UIAxes2, 'off');
            grid(app.UIAxes2, 'on');
            title(app.UIAxes2, sprintf('Diagrama del ojo (%.0f dB)', EbNo_dB));
            xlabel(app.UIAxes2, 'Tiempo (símbolos)');
            ylabel(app.UIAxes2, 'Amplitud');
            xlim(app.UIAxes2, [0 2]);
            
            %% === 7. Gráfica 3: Curva BER ===
            EbNo_range = 0:2:14;
            ber_curva  = zeros(size(EbNo_range));
            
            for i = 1:length(EbNo_range)
                sr = canal_awgn(sen_tx, EbNo_range(i), k);
                sf = conv(sr, h, 'full');
                mu = sf(retardo+1 : sps : retardo + N*sps);
                [br, ~] = decisor(mu, C, k);
                ber_curva(i) = sum(bits_tx ~= br) / length(bits_tx);
            end
            
            ber_plot = ber_curva;
            ber_plot(ber_plot == 0) = 1e-6;
            
            cla(app.UIAxes3);
            semilogy(app.UIAxes3, EbNo_range, ber_plot, ...
                     'b-o', 'LineWidth', 1.5, 'MarkerSize', 6);
            grid(app.UIAxes3, 'on');
            title(app.UIAxes3, 'Curva BER vs Eb/N0');
            xlabel(app.UIAxes3, 'Eb/N0 (dB)');
            ylabel(app.UIAxes3, 'BER');
            ylim(app.UIAxes3, [1e-6 1]);
            
            %% === 8. Gráfica 4: Espectro ===
            Nfft = 2048;
            [PSD_bb, f_bb] = periodogram(sen_tx, hann(length(sen_tx)), ...
                                         Nfft, 1, 'centered');
            PSD_bb_dB = 10*log10(PSD_bb / max(PSD_bb));
            
            cla(app.UIAxes4);
            plot(app.UIAxes4, f_bb, PSD_bb_dB, 'b', 'LineWidth', 1.2);
            grid(app.UIAxes4, 'on');
            title(app.UIAxes4, 'Espectro banda base');
            xlabel(app.UIAxes4, 'Frecuencia normalizada');
            ylabel(app.UIAxes4, 'PSD (dB)');
            xlim(app.UIAxes4, [-0.5 0.5]);
            ylim(app.UIAxes4, [-60 5]);
            
            %% === 9. Métricas (RF4) ===
            eta  = k / (1 + beta);
            Es   = mean(abs(simbolos).^2);
            BW   = 1 * (1 + beta);
            P_bb = mean(abs(sen_tx).^2);
            
            texto = sprintf([ ...
                'η  = %.3f bits/s/Hz\n' ...
                'Es = %.4f\n' ...
                'BW = %.3f (pasa banda)\n' ...
                'P  = %.4f\n' ...
                'BER = %.2e'], eta, Es, BW, P_bb, BER);
            app.MetricasTextArea.Value = texto;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Constelacion Rx')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Diagrama del ojo')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Curva BER')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Espectro')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.ControlesPanel = uipanel(app.GridLayout);
            app.ControlesPanel.Title = 'Controles';
            app.TipoLabel = uilabel(app.ControlesPanel);
            app.TipoLabel.Text = 'Tipo: ';
            app.Label = uilabel(app.ControlesPanel);
            app.Label.Text = '';
            app.TipoDropDown = uidropdown(app.ControlesPanel);
            app.TipoDropDown.Items = {'PAM', 'QAM', 'PSK'};
            app.TipoDropDown.Value = 'QAM';
            app.MLabel = uilabel(app.ControlesPanel);
            app.MLabel.Text = 'M: ';
            app.Label_2 = uilabel(app.ControlesPanel);
            app.Label_2.Text = '';
            app.MEditField = uieditfield(app.ControlesPanel, 'numeric');
            app.MEditField.Value = 16;
            app.BetaLabel = uilabel(app.ControlesPanel);
            app.BetaLabel.Text = 'Beta: ';
            app.BetaEditField = uieditfield(app.ControlesPanel, 'numeric');
            app.BetaEditField.Value = 0.35;
            app.EbN0dBLabel = uilabel(app.ControlesPanel);
            app.EbN0dBLabel.Text = 'Eb/N0 (dB): ';
            app.EbNoEditField = uieditfield(app.ControlesPanel, 'numeric');
            app.EbNoEditField.Value = 10;
            app.NsimbolosLabel = uilabel(app.ControlesPanel);
            app.NsimbolosLabel.Text = 'N (simbolos):';
            app.NEditField = uieditfield(app.ControlesPanel, 'numeric');
            app.NEditField.Value = 5000;
            app.SimularButton = uibutton(app.ControlesPanel, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.MetricasPanel = uipanel(app.GridLayout);
            app.MetricasPanel.Title = 'Metricas';
            app.MetricasTextAreaLabel = uilabel(app.MetricasPanel);
            app.MetricasTextAreaLabel.Text = 'Metricas';
            app.MetricasTextArea = uitextarea(app.MetricasPanel);
            app.MetricasTextArea.Editable = 'off';
            app.MetricasTextArea.Value = {'η = ---'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyecto_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end